classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        Spinner      matlab.ui.control.Spinner
        howManyA     matlab.ui.control.NumericEditField
        sensorePeso  matlab.ui.control.Image
    end

    
    properties (Access = public)
        %% Oggetti
        objects;
        weightSensor;
        
        %% Timer
        masterTimer;
        spawnTimer;

        %% Modbus
        mb;
        plc_connected = false;
        
        %% Labels
        types = ["A", "B", "C", "NotValid"];
        
        %% Variables
        toAddA;
        watchNextA = true;
    end
    
    methods (Access = public)
        %% Master Loop
        function masterLoop(app)
            if app.plc_connected
                try
                    % Scorro su tutti gli oggetti dell'array objects
                    for i = length(app.objects):-1:1
                        obj = app.objects{i};
                        app.update(obj);
                        if obj.shouldDelete
                            delete(obj.graphic);
                            app.objects(i) = [];
                            disp(app.objects)
                        end
                    end
                catch e
                    disp(['Errore lettura coil: ' e.message]);
                end
            end
        end

        %% Funzione per generare gli oggetti
         function spawnObject(app)       
            % Tipo casuale
            value = rand;
            if value < 0.33
                % Peso viti: 1,5 desiderato, variazioni di 0,003 per ogni
                % vite in più o in meno
                weight = 1.491 + rand() * 0.018;
                color = [1 0 0];
            elseif value < 0.66 && value > 0.33
                weight = 2.49 + rand() * 0.02;
                color = [0 1 0];
            else 
                weight = 3 + rand() * 0.5;
                color = [0 0 1];
            end
                                         
            % Creazione grafica
            objGraphic = uilabel(app.UIFigure);     
            objGraphic.BackgroundColor = color;
            objGraphic.Position = [-20 400 40 40];
               
            % Creazione oggetto logico
            obj = IndustrialObject(weight, objGraphic);
               
            % Salvataggio nell'array
            app.objects{end+1} = obj;
               
            disp("Nuovo oggetto creato:")
            disp(weight)
        
         end

        %% Aggiornamento di tutti gli object (forse conviene metterlo dentro la classe IndustrialObject?)
        function update(app, obj)
            % Controllo basato sul tipo (Non categorizzato, A//B/C)
            switch obj.type
                case "NotClassified"
                    obj.move(10, 0)
                    if obj.getX() > app.sensorePeso.Position(1) && obj.getX() < app.sensorePeso.Position(1) + 11
                        app.writeReal(2049, obj.getWeight());
                        obj.setType("WaitingBalanceAndClassification");
                    end
                case "WaitingBalanceAndClassification"
                    valid = read(app.mb, "coils", 1, 1);
                    if valid
                        type = read(app.mb, "holdingregs", 1, 1);
                        obj.setType(app.types(type));
                        obj.setState("Classified")
                    else 
                        obj.setType("Discarded")
                        obj.setState("Discarded")
                    end

                case "A"
                    app.updateA(obj);

                case "B"
                    app.updateB(obj);

                case "C"
                    app.updateC(obj);

                case "Discarded"
                    obj.move(10, 0)
                    if obj.getX() > app.sensorePeso.Position(1) + 150
                        obj.shouldDelete = true;
                    end
            end
        end

        function updateA(app, obj)
            switch obj.state
                % L'oggetto appena classificato esegue la prima parte del
                % movimento
                case "Classified"
                    obj.move(-15,-4);
                    raw = (read(app.mb, "holdingregs", 2, 1));
                    isNegative = int16(bitget(raw,16));
                    app.toAddA = int16(bitset(raw,16,0)) + (-2^15)*isNegative;
                    app.howManyA.Value = double(app.toAddA);
                    if obj.getX() < 100
                        obj.setState("Preparing")
                    end
                % L'utente guarda lo schermo e si prepara a lavorare
                case "Preparing"
                    obj.move(0, -10);
                    if app.watchNextA
                        if app.Spinner.Value < app.toAddA
                            app.Spinner.Value = app.Spinner.Value + 1;
                        elseif app.Spinner.Value > app.toAddA
                            app.Spinner.Value = app.Spinner.Value - 1;
                        else 
                            app.watchNextA = false;
                            obj.setState("Modifing");
                        end
                    end
                % L'utente sta per ricevere la scatola e apportare le
                % opportune modifiche
                case "Modifing"
                    obj.move(0, -15);
                    if obj.getY() < app.Spinner.Position(2)
                        app.Spinner.Value = 0;
                        app.watchNextA = true;
                        obj.setState("Finished");
                    end
                case "Finished"
                    obj.move(0, -15);
                    if obj.getY() < 0
                        obj.shouldDelete = true;
                    end
            end
        end

        function updateB(~, obj)
            switch obj.state
                case "Classified"
                    obj.move(0,-4);
                    
            end
        end

        function updateC(~, obj)
            switch obj.state
                case "Classified"
                    obj.move(10,-4);
                    
            end
        end
        %% Utilities
        % Funzione per leggere un valore reale da openplc
        function value = readReal(app, address)
            regs = read(app.mb, "holdingregs", address, 2);        
            u32 = bitshift(uint32(regs(1)),16) + uint32(regs(2));        
            value = typecast(u32,'single');        
        end
        function writeReal(app, address, value)
        u32 = typecast(single(value), 'uint32');      
            regs = double([ ...
                bitshift(u32,-16), ...
                bitand(u32,65535)
            ]);      
            write(app.mb, "holdingregs", address, regs);      
        end
                
        %% Start Simulation
        function startSimulation(app)
           if ~app.plc_connected
                try 
                    app.mb = modbus('tcpip', '127.0.0.1', 502);
                    app.plc_connected = true;
                catch e
                    disp(e.message)
                    app.plc_connected = false;
                end
           end
        end

        %% Stop simulation
        function stopSimulation(app)
            % Ferma il master timer
            if ~isempty(app.masterTimer)
                stop(app.masterTimer);
            end
            % Chiudi connessione modbus
            if app.plc_connected && ~isempty(app.mb)
                try
                    delete(app.mb)
                catch
                end
                app.plc_connected = false;
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            startSimulation(app);
            if isempty(app.masterTimer) || ~isvalid(app.masterTimer)
                app.masterTimer = timer( ...
                    'ExecutionMode','fixedSpacing', ...
                    'Period', 0.1, ...
                    'BusyMode','drop', ...
                    'TimerFcn',@(~,~) app.masterLoop());
            end

            app.objects = {};
            app.spawnTimer = timer;
            app.spawnTimer.ExecutionMode = 'fixedRate';
            app.spawnTimer.Period = 3;
            app.spawnTimer.TimerFcn = @(~,~) spawnObject(app);

            app.spawnTimer.ErrorFcn = @(~,e) disp(e.Data);
            app.masterTimer.ErrorFcn = @(~,e) disp(e.Data);
            
            if strcmp(app.masterTimer.Running,'off')
                start(app.masterTimer);
                start(app.spawnTimer);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.sensorePeso = uiimage(app.UIFigure);
            app.sensorePeso.ImageSource = fullfile(pathToMLAPP, 'assets', 'sensore di peso.png');
            app.howManyA = uieditfield(app.UIFigure, 'numeric');
            app.Spinner = uispinner(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end